classdef work2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        display        matlab.ui.control.TextArea
        record         matlab.ui.control.Button
        clear          matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
        soundall       matlab.ui.control.Button
        Button_string  matlab.ui.control.Button
        Button_0       matlab.ui.control.Button
        Button_star    matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_1       matlab.ui.control.Button
        h1             matlab.ui.control.UIAxes
        h2             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        keyNames=[];
        tone_all=[];
        Decode_output=[];
        y;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=697;
            f2=1209;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=697;
            f2=1336;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=697;
            f2=1447;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=770;
            f2=1209;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=770;
            f2=1336;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=770;
            f2=1447;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=852;
            f2=1209;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=852;
            f2=1336;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=852;
            f2=1447;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_star
        function Button_starPushed(app, event)
            fs=8000;
            duration=0.5;
            f1=941;
            f2=1209;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            fs=8000;
            duration=0.5;
            f1=941;
            f2=1336;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button pushed function: Button_string
        function Button_stringPushed(app, event)
            fs=8000;
            duration=0.5;
            f1=941;
            f2=1447;
            t=0:1/fs:duration;
            y1=sin(2*pi*f1*t);
            y2=sin(2*pi*f2*t);
            app.y=y1+y2;
            soundsc(app.y,fs);
            app.tone_all=[app.tone_all,zeros(1,400),app.y];
            plot(app.h1,t,app.y);
            axis([0 0.035 -2 2]);
            close all;
            Ak=2*abs(fft(app.y))/length(app.y);
            Ak(1)=Ak(1)/2;
            f=(0:1:(length(app.y)-1)/2)*fs/length(app.y);
            plot(app.h2,f,Ak(1:(length(app.y)+1)/2));
            axis([500 2000 0 1]);
            close all;
        end

        % Button down function: h1
        function h1ButtonDown(app, event)
            app.keyNames=[];
            app.tone_all=[];
            app.Decode_output=[];
        end

        % Button down function: h2
        function h2ButtonDown(app, event)
            app.keyNames=[];
            app.tone_all=[];
            app.Decode_output=[];
        end

        % Value changed function: display
        function displayValueChanged(app, event)
            app.keyNames=[];
            app.tone_all=[];
            app.Decode_output=[];
            value = app.display.Value;
        end

        % Button pushed function: soundall
        function soundallButtonPushed(app, event)
        fs = 8000;  % 采样率
        t = (0:length(app.tone_all)-1) / fs;  % 时间轴
        % 在h1图上显示信号波形
        plot(app.h1, t, app.tone_all);
        grid(app.h1, 'on');
        % 去掉前500和后500个采样点进行音频处理
    for ii = 0:(length(app.tone_all) / 1421 - 1)
        tone = app.tone_all(1 + 1421 * ii : 1421 * (ii + 1));
        tone = tone(401:end);  % 去掉前500和后500个采样点
        % 对每段音调进行FFT，并在h2图中显示频谱
        Ak = 2 * abs(fft(tone)) / length(tone);
        Ak(1) = Ak(1) / 2;  % 调整第一个频率分量
        f = (0:1:(length(tone)-1)/2) * fs / length(tone);  % 频率轴
        plot(app.h2, f, Ak(1:(length(tone)+1)/2));
        grid(app.h2, 'on');
        axis(app.h2, [500 2000 0 1]);  % 设置频谱显示的范围
        % 播放声音
        soundsc(tone, fs);  % 播放音频
        pause(0.25);  % 暂停0.25秒
    end
        % 保存video.wav音频文件
        audiowrite('E:\01-matlabproject\mav\video.wav',(app.tone_all')/2, fs);
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.keyNames=[];
            app.tone_all=[];
            app.Decode_output=[];
            close all;
            cla(app.h1);
            cla(app.h2);
             app.display.Value = '';
        end

        % Button pushed function: record
        function recordButtonPushed(app, event)
                app.Decode_output=[];
                output=[];
                app.tone_all=audioread('E:\01-matlabproject\video.wav'); % load 
                app.tone_all=(app.tone_all')*2;
                fs=8000;
            %% Filter Bank Design
            %设计7个带通滤波器对应行频率和罗列频率的7个不同的频率分量
                a697=[1 -2*cos(2*pi*18/205) 1];
                a770=[1 -2*cos(2*pi*20/205) 1];
                a852=[1 -2*cos(2*pi*22/205) 1];
                a941=[1 -2*cos(2*pi*24/205) 1];
                a1209=[1 -2*cos(2*pi*31/205) 1];
                a1336=[1 -2*cos(2*pi*34/205) 1];
                a1477=[1 -2*cos(2*pi*38/205) 1];
            %% 对tone进行1421个段进行采样分段，滤除前后的400个采样点。
            for ii=0:(length(app.tone_all)/1421-1)
                tone=app.tone_all(1+1421*ii:1421*(ii+1));
                tone=tone(401:end);
                %对录制的音频应用带通滤波器，提取不同的频率成分。
                yDTMF=[tone 0];
                y697=filter(1,a697,yDTMF);
                y770=filter(1,a770,yDTMF);
                y852=filter(1,a852,yDTMF);
                y941=filter(1,a941,yDTMF);
                y1209=filter(1,a1209,yDTMF);
                y1336=filter(1,a1336,yDTMF);
                y1477=filter(1,a1477,yDTMF);
                %对每个滤波器输出的信号进行信号强度计算，通过计算相邻采样点的平方差和滤波器特性得到强度 m
                m(1)=sqrt(y697(206)^2+y697(205)^2-2*cos(2*pi*18/205)*y697(206)*y697(205));
                m(2)=sqrt(y770(206)^2+y770(205)^2-2*cos(2*pi*20/205)*y770(206)*y770(205));
                m(3)=sqrt(y852(206)^2+y852(205)^2-2*cos(2*pi*22/205)*y852(206)*y852(205));
                m(4)=sqrt(y941(206)^2+y941(205)^2-2*cos(2*pi*24/205)*y941(206)*y941(205));
                m(5)=sqrt(y1209(206)^2+y1209(205)^2-2*cos(2*pi*31/205)*y1209(206)*y1209(205));
                m(6)=sqrt(y1336(206)^2+y1336(205)^2-2*cos(2*pi*34/205)*y1336(206)*y1336(205));
                m(7)=sqrt(y1477(206)^2+y1477(205)^2-2*cos(2*pi*38/205)*y1477(206)*y1477(205));
                
                m=2*m/205;
                
                th=sum(m)/4; % based on empirical measurement
                f=[697 770 852 941 1209 1336 1477];
                th=[th th];
                
                idx=find(m>th(1));
                Determination=f(idx);
                switch Determination(1)
                        case {697}
                            switch Determination(2)
                                case {1209}
                                    output='1';
                                case {1336}
                                    output='2';
                                case {1477}
                                    output='3';
                            end
                        case {770}
                            switch Determination(2)
                                case {1209}
                                    output='4';
                                case {1336}
                                    output='5';
                                case {1477}
                                    output='6';
                            end
                        case {852}
                            switch Determination(2)
                                case {1209}
                                    output='7';
                                case {1336}
                                    output='8';
                                case {1477}
                                    output='9';
                            end
                        case {941}
                            switch Determination(2)
                                case {1209}
                                    output='*';
                                case {1336}
                                    output='0';
                                case {1477}
                                    output='#';
                            end
                end
                
                app.Decode_output=[app.Decode_output,output];
                app.Decode_output=string(app.Decode_output);
                app.display.Value=app.Decode_output; % Property
                soundsc(tone,fs);
                pause(0.25);
            end % LOOP
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.h2 = uiaxes(app.UIFigure);
            title(app.h2, '幅度谱')
            xlabel(app.h2, 'f/Hz')
            ylabel(app.h2, '幅度')
            zlabel(app.h2, 'Z')
            app.h2.ButtonDownFcn = createCallbackFcn(app, @h2ButtonDown, true);
            app.h1 = uiaxes(app.UIFigure);
            title(app.h1, '频谱')
            xlabel(app.h1, 't/s')
            ylabel(app.h1, 'f/Hz')
            zlabel(app.h1, 'Z')
            app.h1.ButtonDownFcn = createCallbackFcn(app, @h1ButtonDown, true);
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_star = uibutton(app.UIFigure, 'push');
            app.Button_star.ButtonPushedFcn = createCallbackFcn(app, @Button_starPushed, true);
            app.Button_star.Text = '*';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Button_string = uibutton(app.UIFigure, 'push');
            app.Button_string.ButtonPushedFcn = createCallbackFcn(app, @Button_stringPushed, true);
            app.Button_string.Text = '#';
            app.soundall = uibutton(app.UIFigure, 'push');
            app.soundall.ButtonPushedFcn = createCallbackFcn(app, @soundallButtonPushed, true);
            app.soundall.Text = '频谱';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'号码输入'};
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = '清除';
            app.record = uibutton(app.UIFigure, 'push');
            app.record.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.record.Text = '识别';
            app.display = uitextarea(app.UIFigure);
            app.display.ValueChangedFcn = createCallbackFcn(app, @displayValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = work2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end